classdef two_tank_lab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LED2Lamp                    matlab.ui.control.Lamp
        LED2LampLabel               matlab.ui.control.Label
        LED1Lamp                    matlab.ui.control.Lamp
        LED1LampLabel               matlab.ui.control.Label
        FileLabel                   matlab.ui.control.Label
        SendFileButton              matlab.ui.control.Button
        SystemInfo                  matlab.ui.control.Label
        UserInfo                    matlab.ui.control.Label
        ControlpanelPanel           matlab.ui.container.Panel
        StopButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        EmergLamp                   matlab.ui.control.Lamp
        EmergButton                 matlab.ui.control.Button
        StopLamp                    matlab.ui.control.Lamp
        StartLamp                   matlab.ui.control.Lamp
        Panel                       matlab.ui.container.Panel
        LaboratorioRemotoMaIELabel  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        UploadButton                matlab.ui.control.Button
        DownloadButton              matlab.ui.control.Button
        ConnectRPi                  matlab.ui.control.StateButton
        ConnectLabel                matlab.ui.control.Label
        PLCLabel                    matlab.ui.control.Label
        StartPLCButton              matlab.ui.control.StateButton
        PLCLamp                     matlab.ui.control.Lamp
        RPiLamp                     matlab.ui.control.Lamp
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        myraspi % Description
        modbusServer % Description
        Timer3
        PlotLineOutput1
        PlotLineInput1
        StartReg = 1025
        StopReg = 1026
        EmergReg = 1027
        serialPort = 'COM2' % serial port for monitoring
        serialDevice = ''
        serialPort2 = 'COM3' % serial port for commands
        serialDevice2 = ''
        RPiConnection = '0' 
        uploadedFile = ''
        uploadedPath = ''
        PLCRunning = '0'
        dataSize = 2
        FileSent = '0'
    end
    
    methods (Access = private)
        %% Callbacks
        function readSystemVariables(app, src, ~)
            if src.NumBytesAvailable>0
                try
                    data = read(src, app.dataSize, 'double');
                    led1 = data(1, 1);
                    led2 = data(1, 2);
                    app.turnOnOffLED(app.LED1Lamp, led1, 'red');
                    app.turnOnOffLED(app.LED2Lamp, led2, 'blue');
                catch me
                    app.SystemInfo.Text = me.message;
                end
            end
        end
        %% Timer functions
        function TimerFcn(app, ~, ~)
            writeline(app.serialDevice, 'connected');
        end
        function turnOnOffLED(app, led, state, color)
            if state==1
                led.Color = color;
            else
                led.Color = 'black';
            end
        end
        %% Helper functions 
        function updateControlLEDs(app, colors)
            app.StartLamp.Color = colors{1};
            app.StopLamp.Color = colors{2};
            app.EmergLamp.Color = colors{3};
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % User information
            try
                user = compiler.UserInfo();
                app.UserInfo.Text = strcat('Usuario:', ' ', user.FirstName, ' ', user.Surname);

            catch me
                app.UserInfo.Text = me.message;
                return
            end
            % Plots
            disableDefaultInteractivity(app.UIAxes);
            app.UIAxes.Toolbar.Visible = 'off';
            % Timer
        end

        % Value changed function: ConnectRPi
        function ConnectRPiValueChanged(app, event)
            value = app.ConnectRPi.Value;
            % Check if PLC is running
            if strcmp(app.PLCRunning, '1')
                app.ConnectLabel.Text = 'PLC is running';
                app.ConnectRPi.Value = ~value;
                return
            end
            % Create tcp client
            try
                app.ConnectLabel.Text = 'Setting up TCP communication...';
                client = tcpclient('localhost', 30000);
                pause(1);
            catch
                app.ConnectLabel.Text = 'Unable to set up';
                app.ConnectRPi.Value = ~value;
                return;
            end
            %%
            if value % Connect to raspberry pi
                % Check raspberry pi availability
                try
                    app.ConnectLabel.Text = 'Checking...';
                    status = communicateTCPServer(client, 'check', 'char');
                    pause(1);
                catch
                    app.ConnectLabel.Text = "Couldn't check RPi availability";
                    app.ConnectRPi.Value = ~value;
                    return;
                end
                if strcmp(status, '0') % RPi is available
                    try 
                        app.ConnectLabel.Text = 'Connecting to RPi...';
                        app.RPiConnection = communicateTCPServer(client, 'connect', 'char');
                    catch
                        app.ConnectLabel.Text = "Couldn't send command to TCP server";
                        app.ConnectRPi.Value = ~value;
                        return;
                    end
                    if strcmp(app.RPiConnection, '1') % Connected to RPi
                        app.RPiLamp.Color = 'green';
                        app.ConnectRPi.Text = 'Disconnect from Raspberry Pi';
                        app.ConnectLabel.Text = '';
                        % Create serial ports 
                        app.serialDevice = serialport(app.serialPort, 115200, 'Timeout', 60);
                        configureTerminator(app.serialDevice, 'LF');
                        app.serialDevice2 = serialport(app.serialPort2, 115200);
                        % Start timer
                        app.Timer3 = timer( ...
                            "ExecutionMode", 'fixedRate', ...
                            'Period', 60, ...
                            'BusyMode', 'queue', ...
                            'StartDelay', 1, ...
                            'TimerFcn', @app.TimerFcn);
                        start(app.Timer3);
                    else
                        app.ConnectLabel.Text = "Couldn't connect to RPi";
                        app.ConnectRPi.Value = ~value;
                        return;
                    end
                else % RPi is not available
                    app.ConnectLabel.Text = "RPi isn't available";
                    app.ConnectRPi.Value = ~value;
                end
            else % Disconnect from raspberry pi
                try
                    app.ConnectLabel.Text = 'Disconnecting...';
                    app.RPiConnection = communicateTCPServer(client, 'disconnect', 'char');
                    pause(1);
                catch me
                    app.ConnectLabel.Text = "Couldn't disconnect from RPi";
                    app.SystemInfo.Text = me.message;
                    return;
                end
                app.myraspi = [];
                app.serialDevice = [];
                app.serialDevice2 = [];
                app.RPiLamp.Color = 'red';
                app.ConnectRPi.Text = 'Connect to Raspberry Pi';
                app.ConnectLabel.Text = '';
                stop(app.Timer3);
            end
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            app.FileLabel.Text = '';
            [app.uploadedFile, app.uploadedPath] = uigetfile();
            if ischar(app.uploadedFile)
                app.FileLabel.Text = strcat(app.uploadedFile, ...
                    ' uploaded successfully');
            end
        end

        % Value changed function: StartPLCButton
        function StartPLCButtonValueChanged(app, event)
            value = app.StartPLCButton.Value;
            %% 
            if strcmp(app.RPiConnection, '0') % User is not connected to raspberry pi
                app.PLCLabel.Text = "You're not connected to RPi";
                app.StartPLCButton.Value = ~value;
                return
            end
            if strcmp(app.FileSent, '0') % No file has been sent
                app.PLCLabel.Text = "You've not sent any file";
                app.StartPLCButton.Value = ~value;
                return
            end
            %%
            if value % Initializing PLC program
                try 
                    app.PLCLabel.Text = 'Starting PLC...';
                    app.PLCRunning = writeread(app.serialDevice, 'start');
                catch
                    app.PLCLabel.Text = "Couldn't send serial command";
                    app.StartPLCButton.Value = ~value;
                    return
                end
                %% 
                if strcmp(app.PLCRunning, '1') % PLC running
                    app.PLCLamp.Color = 'green';
                    app.StartPLCButton.Text = 'Stop PCL';
                    app.PLCLabel.Text = '';
                    writeline(app.serialDevice, 'running');
                    % Configure serial port callback
                    try
                        configureCallback(app.serialDevice2, 'byte', 1, @app.readSystemVariables);
                    catch me
                        app.SystemInfo.Text = me.message;
                    end
                else 
                    app.StartPLCButton.Text = 'Start PLC';
                    app.StartPLCButton.Value = ~value;
                    app.PLCLabel.Text = "Couldn't start PLC";
                end
            else % Stopping PLC program
                app.PLCLabel.Text = 'Stopping PLC program...';
                configureCallback(app.serialDevice2, 'off');               
                app.PLCRunning = writeread(app.serialDevice, 'stop');
                if strcmp(app.PLCRunning, '0')
                    app.PLCLamp.Color = 'blue';
                    app.StartPLCButton.Text = 'Start PLC';
                    app.updateControlLEDs({'black', 'black', 'black'});
                    app.PLCLabel.Text = '';
                else
                    app.PLCLabel.Text = "Couldn't stop PLC";
                    app.StartPLCButton.Value = ~value;
                    configureCallback(app.serialDevice2, 'byte', 1, @app.readSystemVariables);
                end
            end
        end

        % Callback function
        function KnobValueChanged(app, event)
                
        end

        % Button pushed function: EmergButton
        function EmergButtonPushed(app, event)
            if strcmp(app.PLCRunning, '1')
                write(app.serialDevice2, [0, 0, 1], 'uint16');
                app.updateControlLEDs({'black', 'black', 'red'});
                return;
            end
            app.SystemInfo.Text = 'PLC is not running';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if strcmp(app.PLCRunning, '1')
                write(app.serialDevice2, [1, 0, 0], 'uint16');
                app.updateControlLEDs({'green', 'black', 'black'});
                return;
            end
            app.SystemInfo.Text = 'PLC is not running';           
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if strcmp(app.PLCRunning, '1')
                write(app.serialDevice2, [0, 1, 0], 'uint16');
                app.updateControlLEDs({'black', 'blue', 'black'});
                return;
            end
        end

        % Callback function
        function CheckConnectionButtonPushed(app, event)

        end

        % Button pushed function: SendFileButton
        function SendFileButtonPushed(app, event)
            if strcmp(app.RPiConnection, '0') % User is not connected to raspberry pi
                app.FileLabel.Text = "You're not connected to RPi";
                return
            end
            if ~ischar(app.uploadedFile)
                app.FileLabel.Text = "You've not uploaded any file";
                return
            end
            %%
            try
                app.FileLabel.Text = 'Sending PLC program...';
                app.FileSent = writeread(app.serialDevice, fullfile(app.uploadedPath, app.uploadedFile));
                app.PLCLabel.Text = '';
                app.FileLabel.Text = '';
                app.PLCLamp.Color = 'blue';
            catch me
                app.FileLabel.Text = "Couldn't send file";
                app.SystemInfo.Text = me.message;
                return
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RPiLamp = uilamp(app.UIFigure);
            app.PLCLamp = uilamp(app.UIFigure);
            app.StartPLCButton = uibutton(app.UIFigure, 'state');
            app.StartPLCButton.ValueChangedFcn = createCallbackFcn(app, @StartPLCButtonValueChanged, true);
            app.StartPLCButton.Text = 'Start PLC';
            app.PLCLabel = uilabel(app.UIFigure);
            app.PLCLabel.Text = '';
            app.ConnectLabel = uilabel(app.UIFigure);
            app.ConnectLabel.Text = '';
            app.ConnectRPi = uibutton(app.UIFigure, 'state');
            app.ConnectRPi.ValueChangedFcn = createCallbackFcn(app, @ConnectRPiValueChanged, true);
            app.ConnectRPi.Text = 'Connect to Raspberry Pi';
            app.DownloadButton = uibutton(app.UIFigure, 'push');
            app.DownloadButton.Icon = fullfile(pathToMLAPP, 'excel_download.png');
            app.DownloadButton.Text = '';
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Icon = fullfile(pathToMLAPP, 'upload.png');
            app.UploadButton.Text = '';
            app.Panel = uipanel(app.UIFigure);
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'MaIE.png');
            app.LaboratorioRemotoMaIELabel = uilabel(app.Panel);
            app.LaboratorioRemotoMaIELabel.Text = {'Laboratorio '; 'Remoto MaIE'};
            app.ControlpanelPanel = uipanel(app.UIFigure);
            app.ControlpanelPanel.Title = 'Control panel';
            app.StartLamp = uilamp(app.ControlpanelPanel);
            app.StopLamp = uilamp(app.ControlpanelPanel);
            app.EmergButton = uibutton(app.ControlpanelPanel, 'push');
            app.EmergButton.ButtonPushedFcn = createCallbackFcn(app, @EmergButtonPushed, true);
            app.EmergButton.Text = 'EMERGENCY';
            app.EmergLamp = uilamp(app.ControlpanelPanel);
            app.StartButton = uibutton(app.ControlpanelPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'START';
            app.StopButton = uibutton(app.ControlpanelPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'STOP';
            app.UserInfo = uilabel(app.UIFigure);
            app.UserInfo.Text = '';
            app.SystemInfo = uilabel(app.UIFigure);
            app.SystemInfo.Text = '';
            app.SendFileButton = uibutton(app.UIFigure, 'push');
            app.SendFileButton.ButtonPushedFcn = createCallbackFcn(app, @SendFileButtonPushed, true);
            app.SendFileButton.Icon = fullfile(pathToMLAPP, 'send_file.png');
            app.SendFileButton.Text = '';
            app.FileLabel = uilabel(app.UIFigure);
            app.FileLabel.Text = '';
            app.LED1LampLabel = uilabel(app.UIFigure);
            app.LED1LampLabel.Text = 'LED 1';
            app.LED1Lamp = uilamp(app.UIFigure);
            app.LED2LampLabel = uilabel(app.UIFigure);
            app.LED2LampLabel.Text = 'LED 2';
            app.LED2Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = two_tank_lab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end